classdef CalibrateLight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InformationMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AppInfoMenu                     matlab.ui.container.Menu
        LoadTextFileMenu                matlab.ui.container.Menu
        BackgroundSubtractionMenu       matlab.ui.container.Menu
        PlotSpectrumMenu                matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        RunCalibrationButton            matlab.ui.control.Button
        PowerReadingUnitsDropDown       matlab.ui.control.DropDown
        PowerReadingLabel               matlab.ui.control.Label
        powerReadingEditField           matlab.ui.control.NumericEditField
        spotDiameterEditField           matlab.ui.control.NumericEditField
        mmEditFieldLabel                matlab.ui.control.Label
        SpotDiameterLabel               matlab.ui.control.Label
        SpectrumLabel                   matlab.ui.control.Label
        LoadIntensitiesButton           matlab.ui.control.Button
        LoadWavelengthsButton           matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LightCalibrationInterfaceLabel  matlab.ui.control.Label
    end

    %% Scott Harris - 2022/02/08 - Built Using CalVoltsToPhotoisomerizations and CalVoltsToPhotons
    
    properties (Access = private)
        wavelengths %1xn or nx1 vector that holds wavelengths;
        intensities %1xn or nx1 vector that holds intensities;
        hasWavelengths = 0; % 0 if wavelengths have been loaded, 1 if not
        hasIntensities = 0; % 0 if intensities have been loaded, 1 if not
        calibrationStructure %output of the calibration
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadWavelengthsButton
        function LoadWavelengthsButtonPushed(app, event)
            [f, path] = uigetfile('.mat', 'Load Wavelengths');
            a = load(fullfile(path, f));
            
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue where the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            
            s = fieldnames(a);
            wavelengths = a.(s{1});
            dims = size(wavelengths);
            if dims(1) ~= 1 && dims(2) ~= 1
                warning('wavelengths must be size 1xn or nx1... nothing loaded, try again')
                return
            end
            
            app.wavelengths = wavelengths;
            app.hasWavelengths = 1;
            if app.hasWavelengths && app.hasIntensities
                app.RunCalibrationButton.Enable = 1;
            end
            disp('Successfully loaded wavelengths')
            
        end

        % Button pushed function: LoadIntensitiesButton
        function LoadIntensitiesButtonPushed(app, event)
            [f, path] = uigetfile('.mat', 'Load Wavelengths');
            a = load(fullfile(path, f));
            
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue where the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            
            s = fieldnames(a);
            intensities = a.(s{1});
            dims = size(intensities);
            if dims(1) ~= 1 && dims(2) ~= 1
                warning('intensities must be size 1xn or nx1... nothing loaded, try again')
                return
            end
            
            app.intensities = intensities;
            app.hasIntensities = 1;
            if app.hasWavelengths && app.hasIntensities
                app.RunCalibrationButton.Enable = 1;
            end
            disp('Successfully loaded intensities')
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            spotDiameter = app.spotDiameterEditField.Value *1e3; %translate to microns
            
            powerReading = app.powerReadingEditField.Value;
            powerReadingUnits = app.PowerReadingUnitsDropDown.Value;
            
            %convert to watts
            switch powerReadingUnits
                case 'uW'
                    powerReading = powerReading *1e-6;
                case 'nW'
                    powerReading = powerReading *1e-9;
                case 'pW'
                    powerReading = powerReading *1e-12;
            end
            
            wavelength = app.wavelengths;
            spectrum = app.intensities;
            %% Light Calibration (taken from calVoltsToPhotoisomerizationsAndPhotons)
            spotArea_mm = pi*(((spotDiameter*1e-3)/2)^2); %in mm^2
            spotArea_um = pi*(((spotDiameter)/2)^2); %in um^2
            
            h = 6.63e-34; %Planck's constant (J*sec)
            c = 299792458; %speed of light (m/sec)
            
            ConeCollectingArea = 0.2; %in um^2
            RodCollectingArea = 0.5; % in um^2
            
            LambdaMaxS = 360; %from Nikonov et al., 2006
            BetaBandScaling = 0.26; %scaling set to value suggested by Govardovskii et al. 2000; note that have no idea whether this is actually the value for Mopsin (or Sopsin)
            SconeSpectrum = GovadorvskiiTemplateA1([LambdaMaxS,BetaBandScaling],wavelength);
            
            LambdaMaxM = 508; %from Nikonov et al., 2006
            MconeSpectrum = GovadorvskiiTemplateA1([LambdaMaxM,BetaBandScaling],wavelength);
            
            LambdaMaxRod = 493.3022; %these are still from Baylor primate recordings
            BetaBandScalingRod = 0.1233;
            RodSpectrum = GovadorvskiiTemplateA1([LambdaMaxRod,BetaBandScalingRod],wavelength);
            
            
            normSpectrum = spectrum / (sum(spectrum)*(wavelength(2)-wavelength(1))); %note that have to multiply by spectrum step to integrate
            photonSpec = normSpectrum.*(wavelength*1e-9)/(h*c);
            
            Scone = sum(photonSpec .* SconeSpectrum);
            Mcone = sum(photonSpec .* MconeSpectrum);
            Rod = sum(photonSpec .* RodSpectrum);
            
            LEDwatts = powerReading;
            LEDwatts_perArea = LEDwatts/spotArea_mm; %W/mm^2
            
            LEDphotons = sum(LEDwatts*photonSpec);
            LEDphotonsPerAreaPerSecond = LEDphotons/spotArea_mm; %photons/s/mm^2
            
            Photoisomerizations_Mcone = sum(LEDwatts*Mcone);
            Photoisomerizations_Scone = sum(LEDwatts*Scone);
            Photoisomerizations_Rod = sum(LEDwatts*Rod);
            
            PhotoisomerizationsPerArea_Mcone = ConeCollectingArea*Photoisomerizations_Mcone/spotArea_um; %photons/um^2
            PhotoisomerizationsPerArea_Scone = ConeCollectingArea*Photoisomerizations_Scone/spotArea_um; %photons/um^2
            PhotoiosomerizationsPerArea_Rod = RodCollectingArea*Photoisomerizations_Rod/spotArea_um; %photons/um^2
            Photoisomerizations_MSR = [PhotoisomerizationsPerArea_Mcone, PhotoisomerizationsPerArea_Scone, PhotoiosomerizationsPerArea_Rod];
            
            calibrationStructure.Watts_per_sqmm_per_s = LEDwatts_perArea;
            calibrationStructure.Photons_per_sqmm_per_second = LEDphotonsPerAreaPerSecond;
            calibrationStructure.Photoisomerizations_Mcone_per_second = Photoisomerizations_Mcone;
            calibrationStructure.Photoisomerizations_Scone_per_second = Photoisomerizations_Scone;
            calibrationStructure.Photoisomerizations_Rod_per_second = Photoisomerizations_Rod;
            calibrationStructure.Photoisomerizations_per_Mcone_per_second = PhotoisomerizationsPerArea_Mcone;
            calibrationStructure.Photoisomerizations_per_Scone_per_second = PhotoisomerizationsPerArea_Scone;
            calibrationStructure.Photoisomerizations_per_Rod_per_second = PhotoiosomerizationsPerArea_Rod;
            calibrationStructure.Photoisomerizations_MSR = Photoisomerizations_MSR;
            calibrationStructure.spotDiameter_mm = app.spotDiameterEditField.Value;
            calibrationStructure.powerReading = app.powerReadingEditField.Value;
            calibrationStructure.powerReadingUnits = app.PowerReadingUnitsDropDown.Value;
            calibrationStructure.date = datetime('today');
            assignin('base', 'CalibrationStructure', calibrationStructure)
            
            app.calibrationStructure = calibrationStructure;
            disp('Success! CalibrationStructure saved to your matlab workspace')
            
        end

        % Menu selected function: LoadTextFileMenu
        function LoadTextFileMenuSelected(app, event)
            %Load wavelengths and intensities directly from a .txt file
            
            [f, p] = uigetfile('*.txt', 'Select Text File', 'Calibrations');
            fname = fullfile(p, f);
            textImport = readmatrix(fname);
            app.wavelengths = textImport(9:end, 1);
            disp('Successfully loaded wavelengths')
            
            app.intensities = textImport(9:end, 3);
            disp('Successfully loaded intensities')
            
            app.RunCalibrationButton.Enable = 1;
            drawnow;
            figure(app.UIFigure)
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            CalibrationStructure = app.calibrationStructure;
            uisave('CalibrationStructure', '.mat')
            drawnow;
            figure(app.UIFigure)
        end

        % Menu selected function: PlotSpectrumMenu
        function PlotSpectrumMenuSelected(app, event)
            quitEarly = 0;
            if ~isvector(app.intensities)
                disp('Load spectrum intensities first')
                quitEarly = 1;
            end
            if ~isvector(app.wavelengths)
                disp('Load spectrum wavelengths first')
                quitEarly = 1;
            end
            
            if quitEarly
                return
            end
            
            figure
            title('Spectrum')
            hold on
            plot(app.wavelengths, app.intensities)
            xlabel('Wavelengths')
            ylabel('Intensities')
        end

        % Callback function: not associated with a component
        function AddBaselineMenuSelected(app, event)
            [f, p] = uigetfile('*.txt', 'Select Text File', 'Calibrations');
            fname = fullfile(p, f);
            textImport = readmatrix(fname);
            backgroundWavelengths = textImport(9:end, 1);            
            backgroundIntensities = textImport(9:end, 3);
            
            if sum(backgroundWavelengths == app.wavelengths) ~= numel(backgroundWavelengths)
                disp('Background data is not the same size as signal. Cannot perform background subtraction')
                return
            end
            app.intensities = app.intensities - backgroundIntensities;
            drawnow;
            disp('Background subtraction Successful. To undo this action, reload your original spectra.')
        end

        % Menu selected function: AppInfoMenu
        function AppInfoMenuSelected(app, event)
              msgbox(['Code adopted from Jon Cafaro CalVoltsToPhotons/Photoisomerizations for the Dunn Lab - February 2022 Scott Harris. The code calculates' ...
                ' photoisomerizations per photoreceptor type using collecting areas and a convolution of the measured light spectrum with opsin absorption spectra. ' ...
                'These calculations are best used for experiments in dissected retinae as they do not take into account the optics of the eye.'], 'Light Calibration Information')
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            web('CalibrateLightHelp.html')
        end

        % Menu selected function: BackgroundSubtractionMenu
        function BackgroundSubtractionMenuSelected(app, event)
             [f, p] = uigetfile('*.txt', 'Select Text File', 'Calibrations');
            fname = fullfile(p, f);
            textImport = readmatrix(fname);
            backgroundWavelengths = textImport(9:end, 1);            
            backgroundIntensities = textImport(9:end, 3);
            
            if sum(backgroundWavelengths == app.wavelengths) ~= numel(backgroundWavelengths)
                disp('Background data is not the same size as signal. Cannot perform background subtraction')
                return
            end
            app.intensities = app.intensities - backgroundIntensities;
            drawnow;
            disp('Background subtraction Successful. To undo this action, reload your original spectra.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformationMenu = uimenu(app.UIFigure);
            app.InformationMenu.Text = 'Information';
            app.HelpMenu = uimenu(app.InformationMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.AppInfoMenu = uimenu(app.InformationMenu);
            app.AppInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @AppInfoMenuSelected, true);
            app.AppInfoMenu.Text = 'App Info';
            app.LoadTextFileMenu = uimenu(app.UIFigure);
            app.LoadTextFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTextFileMenuSelected, true);
            app.LoadTextFileMenu.Tooltip = {'Load wavelength and intensities from standard format text file.'};
            app.LoadTextFileMenu.Text = 'Load Text File';
            app.BackgroundSubtractionMenu = uimenu(app.LoadTextFileMenu);
            app.BackgroundSubtractionMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgroundSubtractionMenuSelected, true);
            app.BackgroundSubtractionMenu.Text = 'Background Subtraction';
            app.PlotSpectrumMenu = uimenu(app.UIFigure);
            app.PlotSpectrumMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotSpectrumMenuSelected, true);
            app.PlotSpectrumMenu.Text = 'Plot Spectrum';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LightCalibrationInterfaceLabel = uilabel(app.UIFigure);
            app.LightCalibrationInterfaceLabel.Text = 'Light Calibration Interface';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Instructions: Load your measured spectrum - wavelengths (nm) and the intensity readings for each wavelength - as vectors from .mat files. Then enter your stimulus spot size (mm) and power reading. Press run to calculate photoisomerizations. A structure with results called CalibrationStructure will be sent to your matlab workspace.';
            app.LoadWavelengthsButton = uibutton(app.UIFigure, 'push');
            app.LoadWavelengthsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWavelengthsButtonPushed, true);
            app.LoadWavelengthsButton.Tooltip = {'nx1 or 1xn matlab vector (measured in nm)'; ''};
            app.LoadWavelengthsButton.Text = 'Load Wavelengths';
            app.LoadIntensitiesButton = uibutton(app.UIFigure, 'push');
            app.LoadIntensitiesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIntensitiesButtonPushed, true);
            app.LoadIntensitiesButton.Tooltip = {'nx1 or 1xn matlab vector'; ''};
            app.LoadIntensitiesButton.Text = 'Load Intensities';
            app.SpectrumLabel = uilabel(app.UIFigure);
            app.SpectrumLabel.Text = 'Spectrum';
            app.SpotDiameterLabel = uilabel(app.UIFigure);
            app.SpotDiameterLabel.Text = 'Spot Diameter';
            app.mmEditFieldLabel = uilabel(app.UIFigure);
            app.mmEditFieldLabel.Text = 'mm';
            app.spotDiameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.spotDiameterEditField.Tooltip = {'Enter diameter of the stimulus spot in mm. If the stimulus is bigger than the detector, use detector diameter of 10.5mm '};
            app.spotDiameterEditField.Value = 10.5;
            app.powerReadingEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerReadingLabel = uilabel(app.UIFigure);
            app.PowerReadingLabel.Text = 'Power Reading';
            app.PowerReadingUnitsDropDown = uidropdown(app.UIFigure);
            app.PowerReadingUnitsDropDown.Items = {'Watts', 'uW', 'nW', 'pW'};
            app.PowerReadingUnitsDropDown.Value = 'Watts';
            app.RunCalibrationButton = uibutton(app.UIFigure, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Enable = 'off';
            app.RunCalibrationButton.Text = 'Run Calibration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalibrateLight

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end